classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Punto4Label                    matlab.ui.control.Label
        Punto7Label                    matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        ElijeDropDown                  matlab.ui.control.DropDown
        ElijeDropDownLabel             matlab.ui.control.Label
        ResultadosTextArea             matlab.ui.control.TextArea
        ResultadosTextAreaLabel        matlab.ui.control.Label
        BuscarButton                   matlab.ui.control.Button
        Punto8Label                    matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        DigitelaIDEditField            matlab.ui.control.EditField
        DigitelaIDEditFieldLabel       matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        TextAreaLabel                  matlab.ui.control.Label
        CalcularButton                 matlab.ui.control.Button
        VariableDropDown               matlab.ui.control.DropDown
        VariabledeintersDropDownLabel  matlab.ui.control.Label
        GraficarButton                 matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end


  properties (Access = private)
        HeartData
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
    
    if exist('HeartData.mat', 'file')
        load('HeartData.mat', 'T');
        app.HeartData = T;
        disp('Datos cargados correctamente.');
    else
        
        if exist('/MATLAB Drive/Guia4/heart_data.csv', 'file')
            T = readtable('/MATLAB Drive/Guia4/heart_data.csv');
            
            
            T.age = T.age / 365;
            T.smoke = categorical(T.smoke, [0, 1], {'no fuma', 'si fuma'});
            T.alco = categorical(T.alco, [0, 1], {'no toma alcohol', 'si toma alcohol'});
            T.active = categorical(T.active, [0, 1], {'no es activo fisicamente', 'es activo fisicamente'});
            T.cardio = categorical(T.cardio, [0, 1], {'no sufre de enfermedades cardiovasculares', 'si sufre de enfermedades cardiovasculares'});
            T.height = T.height / 100;
            T.IMC = T.weight ./ (T.height.^2);

            T.IMC_Category = categorical(zeros(size(T.IMC)), [0, 1, 2, 3], {'Bajo peso', 'Normal', 'Sobrepeso', 'Obesidad'});
            T.IMC_Category(T.IMC < 18.5) = 'Bajo peso';
            T.IMC_Category(T.IMC >= 18.5 & T.IMC <= 24.9) = 'Normal';
            T.IMC_Category(T.IMC >= 25.0 & T.IMC <= 29.9) = 'Sobrepeso';
            T.IMC_Category(T.IMC >= 30.0) = 'Obesidad';

            T.Presion_Media = (T.ap_hi + 2 * T.ap_lo) / 3;
            
            
            save('HeartData.mat', 'T');
            app.HeartData = T;
            disp('Datos procesados y guardados correctamente.');
        else
            disp('Error: Archivo de datos no encontrado.');
        end
    end

        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
 value = app.ElijeDropDown.Value;
            cla(app.UIAxes, "reset");  
            switch value
                case 'Barras'  % Corregido
                    hombres = app.HeartData(app.HeartData.gender == 1, :); 
                    mujeres = app.HeartData(app.HeartData.gender == 2, :); 

                    rangos_edades = [30, 40; 40, 50; 50, 60];
                    enfermedades_hombres = zeros(size(rangos_edades, 1), 1);
                    enfermedades_mujeres = zeros(size(rangos_edades, 1), 1);

                    for i = 1:size(rangos_edades, 1)
                        edad_min = rangos_edades(i, 1);
                        edad_max = rangos_edades(i, 2);
                        hombres_rango_edad = hombres(hombres.age >= edad_min & hombres.age < edad_max, :);
                        enfermedades_hombres(i) = sum(hombres_rango_edad.cardio == 'si sufre de enfermedades cardiovasculares');
                        mujeres_rango_edad = mujeres(mujeres.age >= edad_min & mujeres.age < edad_max, :);
                        enfermedades_mujeres(i) = sum(mujeres_rango_edad.cardio == 'si sufre de enfermedades cardiovasculares');
                    end

                    bar(app.UIAxes, rangos_edades(:, 1), [enfermedades_hombres, enfermedades_mujeres], 'grouped');
                    title(app.UIAxes, 'Enfermedades Cardiovasculares por Rango de Edad y Género');
                    xlabel(app.UIAxes, 'Edad');
                    ylabel(app.UIAxes, 'Número de Enfermedades Cardiovasculares');
                    legend(app.UIAxes, 'Hombres', 'Mujeres');
                    app.UIAxes.XTick = rangos_edades(:, 1);
                    app.UIAxes.XTickLabel = {'30-40', '40-50', '50-60'};
                     case 'Dispercion'  % Corregido
                    idxWith = app.HeartData.cardio == 'si sufre de enfermedades cardiovasculares';
                    idxWithout = app.HeartData.cardio == 'no sufre de enfermedades cardiovasculares';

                    scatter(app.UIAxes, app.HeartData.IMC(idxWith), app.HeartData.cholesterol(idxWith), 'r');
                    hold(app.UIAxes, 'on');
                    scatter(app.UIAxes, app.HeartData.IMC(idxWithout), app.HeartData.cholesterol(idxWithout), 'b');
                    hold(app.UIAxes, 'off');

                    title(app.UIAxes, 'IMC vs Colesterol');
                    xlabel(app.UIAxes, 'Índice de Masa Corporal (IMC)');
                    ylabel(app.UIAxes, 'Colesterol');
                    legend(app.UIAxes, {'Con enfermedad', 'Sin enfermedad'});
            end
           
        end

        % Value changed function: ElijeDropDown
        function ElijeDropDownValueChanged(app, event)
         GraficarButtonPushed(app);
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)

    variableSeleccionada = app.VariableDropDown.Value;
    
    
    load('HeartData.mat');
    
    
    conEnfermedad = T(T.cardio == 'si sufre de enfermedades cardiovasculares', :);
    sinEnfermedad = T(T.cardio == 'no sufre de enfermedades cardiovasculares', :);
    
   
    if strcmp(variableSeleccionada, 'Índice de masa corporal')
      
        mediaCon = mean(conEnfermedad.IMC);
        desviacionCon = std(conEnfermedad.IMC);
        
        
        mediaSin = mean(sinEnfermedad.IMC);
        desviacionSin = std(sinEnfermedad.IMC);
    elseif strcmp(variableSeleccionada, 'Presión arterial media')
        
        mediaCon = mean(conEnfermedad.Presion_Media);
        desviacionCon = std(conEnfermedad.Presion_Media);
        
        
        mediaSin = mean(sinEnfermedad.Presion_Media);
        desviacionSin = std(sinEnfermedad.Presion_Media);
    elseif strcmp(variableSeleccionada, 'Colesterol')
        
        mediaCon = mean(conEnfermedad.cholesterol);
        desviacionCon = std(conEnfermedad.cholesterol);
        
        
        mediaSin = mean(sinEnfermedad.cholesterol);
        desviacionSin = std(sinEnfermedad.cholesterol);
    end
    
    
    textoResultado = sprintf('Con enfermedades cardiovasculares:\nMedia: %.2f\nDesviación estándar: %.2f\n\n', mediaCon, desviacionCon);
    textoResultado = strcat(textoResultado, sprintf('\nSin enfermedades cardiovasculares:\nMedia: %.2f\nDesviación estándar: %.2f\n', mediaSin, desviacionSin));
    app.TextArea.Value = textoResultado;
        end

        % Value changed function: VariableDropDown
        function VariableDropDownValueChanged(app, event)
     
    app.TextArea.Value = '';
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
            
        end

        % Value changed function: DigitelaIDEditField
        function DigitelaIDEditFieldValueChanged(app, event)
             
            idBuscado = str2double(app.DigitelaIDEditField.Value);
            
            
            load('HeartData.mat');
            
            
            participante = T(T.id == idBuscado, :);
            
            
            if isempty(participante)
                
                app.ResultadosTextArea.Value = 'No se encontró ningún participante con ese ID.';
                return;
            end
            
            
            enfermedadCardiovascular = participante.cardio;
            fumador = participante.smoke;
            tomaAlcohol = participante.alco;
            actividadFisica = participante.active;
            categoriaIMC = participante.IMC_Category;
            
           
            textoResultado = sprintf('Presencia de enfermedades cardiovasculares: \n%s', char(enfermedadCardiovascular));
            textoResultado = strcat(textoResultado, sprintf('\nFumador: %s\n', char(fumador)));
            textoResultado = strcat(textoResultado, sprintf('\nToma alcohol: %s\n', char(tomaAlcohol)));
            textoResultado = strcat(textoResultado, sprintf('\nActividad física: %s\n', char(actividadFisica)));
            textoResultado = strcat(textoResultado, sprintf('\nCategoría IMC: %s\n', char(categoriaIMC)));
            
            
            app.ResultadosTextArea.Value = textoResultado;
            
        end

        % Value changed function: ResultadosTextArea
        function ResultadosTextAreaValueChanged(app, event)
            value = app.ResultadosTextArea.Value;
            
        end

        % Button pushed function: BuscarButton
        function BuscarButtonPushed(app, event)
            DigitelaIDEditFieldValueChanged(app, event);
       
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.VariabledeintersDropDownLabel = uilabel(app.UIFigure);
            app.VariabledeintersDropDownLabel.Text = 'Variable de interés';
            app.VariableDropDown = uidropdown(app.UIFigure);
            app.VariableDropDown.Items = {'Índice de masa corporal', 'Presión arterial media', 'Colesterol'};
            app.VariableDropDown.ValueChangedFcn = createCallbackFcn(app, @VariableDropDownValueChanged, true);
            app.VariableDropDown.Value = 'Índice de masa corporal';
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.DigitelaIDEditFieldLabel = uilabel(app.UIFigure);
            app.DigitelaIDEditFieldLabel.Text = 'Digite la ID';
            app.DigitelaIDEditField = uieditfield(app.UIFigure, 'text');
            app.DigitelaIDEditField.ValueChangedFcn = createCallbackFcn(app, @DigitelaIDEditFieldValueChanged, true);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Punto8Label = uilabel(app.UIFigure);
            app.Punto8Label.Text = 'Punto 8';
            app.BuscarButton = uibutton(app.UIFigure, 'push');
            app.BuscarButton.ButtonPushedFcn = createCallbackFcn(app, @BuscarButtonPushed, true);
            app.BuscarButton.Text = 'Buscar';
            app.ResultadosTextAreaLabel = uilabel(app.UIFigure);
            app.ResultadosTextAreaLabel.Text = 'Resultados';
            app.ResultadosTextArea = uitextarea(app.UIFigure);
            app.ResultadosTextArea.ValueChangedFcn = createCallbackFcn(app, @ResultadosTextAreaValueChanged, true);
            app.ElijeDropDownLabel = uilabel(app.UIFigure);
            app.ElijeDropDownLabel.Text = 'Elije';
            app.ElijeDropDown = uidropdown(app.UIFigure);
            app.ElijeDropDown.Items = {'Barras', 'Dispercion'};
            app.ElijeDropDown.ValueChangedFcn = createCallbackFcn(app, @ElijeDropDownValueChanged, true);
            app.ElijeDropDown.Value = 'Barras';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Punto7Label = uilabel(app.UIFigure);
            app.Punto7Label.Text = 'Punto 7';
            app.Punto4Label = uilabel(app.UIFigure);
            app.Punto4Label.Text = 'Punto 4';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end